classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        DataPathPanel      matlab.ui.container.Panel
        PathSelectButton   matlab.ui.control.Button
        DataPathEditField  matlab.ui.control.EditField
        ControlPanel       matlab.ui.container.Panel
        StartButton        matlab.ui.control.Button
        AddSubjectButton   matlab.ui.control.Button
        SetParamsButton    matlab.ui.control.Button
        NextButton         matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
        StatePanel         matlab.ui.container.Panel
        StateLamp          matlab.ui.control.Lamp
        StateLabel         matlab.ui.control.Label
        PhaseSelectPanel   matlab.ui.container.Panel
        PhaseSelectTree    matlab.ui.container.CheckBoxTree
        TBPassiveNode      matlab.ui.container.TreeNode
        TBPhase1Node       matlab.ui.container.TreeNode
        TBPhase2Node       matlab.ui.container.TreeNode
        TBPhase3Node       matlab.ui.container.TreeNode
        TBPhase4Node       matlab.ui.container.TreeNode
        TBActiveNode       matlab.ui.container.TreeNode
        TBPhase5Node       matlab.ui.container.TreeNode
        TBPhase6Node       matlab.ui.container.TreeNode
        TBPhase7Node       matlab.ui.container.TreeNode
        MSTINode           matlab.ui.container.TreeNode
        MSTIPhase1Node     matlab.ui.container.TreeNode
        MSTIPhase2Node     matlab.ui.container.TreeNode
        MSTIPhase3Node     matlab.ui.container.TreeNode
    end


    properties (Access = public)
        subjectInfo
        params
        dataPath
        pIDList
        protocolList
        protocol
        pIDIndex
        status
        mTimer
    end
    
    methods (Access = public)
        
        function timerInit(app)
            app.mTimer = timer;
            app.mTimer.Period = 1;
            app.mTimer.ExecutionMode = 'fixedSpacing';
            app.mTimer.TimerFcn = @(~, ~) timerHandler(app);
        end

        function timerHandler(app)
            currentTime = app.params.intervalMax * 60 - app.mTimer.TasksExecuted;
            
            if currentTime == 0
                NextButtonPushed(app, []);
            else
                app.NextButton.Text = ['下一阶段(', num2str(currentTime), 's)'];
                drawnow;
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = '时间绑定任务刺激系统';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            try
                load('config.mat', 'config');
                app.params = config.params;
                app.dataPath = config.dataPath;
                app.subjectInfo = config.subjectInfo;
                app.dataPath = config.dataPath;
                app.DataPathEditField.Value = config.dataPath;
            catch
                app.params.nRepeat = 40;
                app.params.fs = 384; % kHz
                app.params.volumn = 0.3;
                app.params.choiceWin = 2; % sec
                app.params.intervalMax = 10; % min
                app.params.triggerType = 'LTP';
                app.params.address = hex2dec('378');

                %% TODO 1: Add/Insert your new protocol ISI here
                app.params.ISIs = [4, ...
                                   4, ...
                                   4, ...
                                   6, ...
                                   5, ...
                                   3.5, ...
                                   5, ...
                                   16, ...
                                   16, ...
                                   16]; % sec
            end

            app.status = 'stop';
            app.StateLamp.Color = [0.8, 0.8, 0.8];
            
            %% TODO 2: Add/Insert your new protocol name here
            app.protocolList = {'时间绑定被动部分 - 第一阶段 - 离散度', ...
                                '时间绑定被动部分 - 第二阶段 - 长度', ...
                                '时间绑定被动部分 - 第三阶段 - 精度', ...
                                '时间绑定被动部分 - 第四阶段 - 跨度', ...
                                '时间绑定主动部分 - 第一阶段 - 精度', ...
                                '时间绑定主动部分 - 第二阶段 - 精度(有间隔)', ...
                                '时间绑定主动部分 - 第三阶段 - 离散度', ...
                                'MSTI部分 - 第一阶段 - 无行为Part1', ...
                                'MSTI部分 - 第二阶段 - 无行为Part2', ...
                                'MSTI部分 - 第三阶段 - 行为'};
            app.protocol = {'passive1', ...
                            'passive2', ...
                            'passive3', ...
                            'passive4', ...
                            'active1', ...
                            'active2', ...
                            'active3', ...
                            'passive5', ...
                            'passive6', ...
                            'active4'};

            expand(app.PhaseSelectTree);
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            subjectInfoInputApp(app, app.subjectInfo);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                InitializePsychSound;
                PsychPortAudio('Close');
                
                if isempty(app.dataPath)
                    app.dataPath = pwd;
                end
    
                config.params = app.params;
                config.dataPath = app.dataPath;
                config.subjectInfo = app.subjectInfo;
                config.time = datestr(now, 'yyyy-mm-dd HH:MM:ss');
                save('config.mat', 'config');
    
                app.params.ioObj = InitTrigger(app.params.triggerType);
    
                %% TODO 3: Add your new protocol in design view
                app.pIDList = [app.PhaseSelectTree.CheckedNodes.NodeData];
                app.pIDList(app.pIDList > 100) = [];
                app.pIDIndex = 1;
                app.StateLabel.Text = app.protocolList{app.pIDList(app.pIDIndex)};
                
                temp = fullfile(app.dataPath, [datestr(now, 'yyyymmdd'), '-', app.subjectInfo.ID]);
                mkdir(temp);
                save(fullfile(temp, 'config.mat'), 'config');
                app.DataPathEditField.Value = app.dataPath;
    
                app.AddSubjectButton.Enable = 'off';
                app.SetParamsButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.PhaseSelectTree.Enable = 'off';
    
                app.status = 'start';
                app.StateLamp.Color = [0, 1, 0];
    
                drawnow;

                %% TODO 4: Define active protocol ID here
                if ismember(app.pIDList(app.pIDIndex), [5, 6, 7, 10])
                    activeFcn(app);
                else
                    passiveFcn(app);
                end
                
            catch ME
                msgbox(ME.message, 'Error');
            end
        end

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            paramsSettingApp(app, app.params);
        end

        % Button pushed function: PathSelectButton
        function PathSelectButtonPushed(app, event)
            temp = uigetdir;

            if temp ~= 0
                app.dataPath = temp;
                app.DataPathEditField.Value = temp;
            end

        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            app.dataPath = app.DataPathEditField.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.AddSubjectButton.Enable = 'on';
            app.SetParamsButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.NextButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.PhaseSelectTree.Enable = 'on';

            app.StateLabel.Text = ['终止于: ', app.protocolList{app.pIDList(app.pIDIndex)}];

            try
                stop(app.mTimer);
                delete(app.mTimer);
            end

            app.NextButton.Text = '下一阶段';
            app.status = 'stop';
            app.StateLamp.Color = [0.8, 0.8, 0.8];
            drawnow;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.NextButton.Enable = 'off';
            app.NextButton.Text = '下一阶段';
            drawnow;
            
            try
                stop(app.mTimer);
                delete(app.mTimer);
            end

            app.pIDIndex = app.pIDIndex + 1;

            if app.pIDIndex <= length(app.pIDList)
                app.StateLabel.Text = app.protocolList{app.pIDList(app.pIDIndex)};
                drawnow;
                
                %% TODO 4: Define active protocol ID here
                if ismember(app.pIDList(app.pIDIndex), [5, 6, 7, 10])
                    activeFcn(app);
                else
                    passiveFcn(app);
                end

            else
                StopButtonPushed(app, event);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '时间绑定任务刺激系统';
            app.PhaseSelectPanel = uipanel(app.Panel);
            app.PhaseSelectPanel.Title = '试验阶段选择';
            app.PhaseSelectTree = uitree(app.PhaseSelectPanel, 'checkbox');
            app.TBPassiveNode = uitreenode(app.PhaseSelectTree);
            app.TBPassiveNode.NodeData = 101;
            app.TBPassiveNode.Tag = 'tbPassiveNode';
            app.TBPassiveNode.Text = '时间绑定被动部分';
            app.TBPhase1Node = uitreenode(app.TBPassiveNode);
            app.TBPhase1Node.NodeData = 1;
            app.TBPhase1Node.Tag = 'tbPhase1Node';
            app.TBPhase1Node.Text = '第一阶段-离散度';
            app.TBPhase2Node = uitreenode(app.TBPassiveNode);
            app.TBPhase2Node.NodeData = 2;
            app.TBPhase2Node.Tag = 'tbPhase2Node';
            app.TBPhase2Node.Text = '第二阶段-长度';
            app.TBPhase3Node = uitreenode(app.TBPassiveNode);
            app.TBPhase3Node.NodeData = 3;
            app.TBPhase3Node.Tag = 'tbPhase3Node';
            app.TBPhase3Node.Text = '第三阶段-精度';
            app.TBPhase4Node = uitreenode(app.TBPassiveNode);
            app.TBPhase4Node.NodeData = 4;
            app.TBPhase4Node.Tag = 'tbPhase4Node';
            app.TBPhase4Node.Text = '第四阶段-跨度';
            app.TBActiveNode = uitreenode(app.PhaseSelectTree);
            app.TBActiveNode.NodeData = 102;
            app.TBActiveNode.Tag = 'tbActiveNode';
            app.TBActiveNode.Text = '时间绑定主动部分';
            app.TBPhase5Node = uitreenode(app.TBActiveNode);
            app.TBPhase5Node.NodeData = 5;
            app.TBPhase5Node.Tag = 'tbPhase5Node';
            app.TBPhase5Node.Text = '第一阶段-精度';
            app.TBPhase6Node = uitreenode(app.TBActiveNode);
            app.TBPhase6Node.NodeData = 6;
            app.TBPhase6Node.Text = '第二阶段-精度(有间隔)';
            app.TBPhase7Node = uitreenode(app.TBActiveNode);
            app.TBPhase7Node.NodeData = 7;
            app.TBPhase7Node.Tag = 'tbPhase6Node';
            app.TBPhase7Node.Text = '第三阶段-离散度';
            app.MSTINode = uitreenode(app.PhaseSelectTree);
            app.MSTINode.NodeData = 201;
            app.MSTINode.Tag = 'nstiNode';
            app.MSTINode.Text = 'MSTI部分';
            app.MSTIPhase1Node = uitreenode(app.MSTINode);
            app.MSTIPhase1Node.NodeData = 8;
            app.MSTIPhase1Node.Tag = 'mstiPhase1Node';
            app.MSTIPhase1Node.Text = '第一阶段-无行为Part1';
            app.MSTIPhase2Node = uitreenode(app.MSTINode);
            app.MSTIPhase2Node.NodeData = 9;
            app.MSTIPhase2Node.Tag = 'mstiPhase2Node';
            app.MSTIPhase2Node.Text = '第二阶段-无行为Part2';
            app.MSTIPhase3Node = uitreenode(app.MSTINode);
            app.MSTIPhase3Node.NodeData = 10;
            app.MSTIPhase3Node.Tag = 'mstiPhase3Node';
            app.MSTIPhase3Node.Text = '第三阶段-行为';
            % Assign Checked Nodes
            app.PhaseSelectTree.CheckedNodes = [app.TBPhase5Node, app.TBPhase6Node, app.TBPhase1Node, app.TBPhase2Node, app.TBPhase3Node];
            app.StatePanel = uipanel(app.Panel);
            app.StatePanel.Title = '当前阶段';
            app.StateLabel = uilabel(app.StatePanel);
            app.StateLabel.Tag = 'stateLabel';
            app.StateLabel.Text = '未开始';
            app.StateLamp = uilamp(app.StatePanel);
            app.ControlPanel = uipanel(app.Panel);
            app.ControlPanel.Title = '控制面板';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tag = 'stopButton';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '试验终止';
            app.NextButton = uibutton(app.ControlPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'nextButton';
            app.NextButton.Enable = 'off';
            app.NextButton.Text = '下一阶段';
            app.SetParamsButton = uibutton(app.ControlPanel, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Tag = 'setParamsButton';
            app.SetParamsButton.Text = '刺激参数设置';
            app.AddSubjectButton = uibutton(app.ControlPanel, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Tag = 'addSubjectButton';
            app.AddSubjectButton.Text = '编辑被试信息';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'startButton';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = '开始';
            app.DataPathPanel = uipanel(app.Panel);
            app.DataPathPanel.Title = '数据存储根目录';
            app.DataPathEditField = uieditfield(app.DataPathPanel, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.DataPathEditField.Tag = 'dataPath';
            app.DataPathEditField.Placeholder = '请输入数据存储根目录（将以日期-被试编号格式创建文件夹）';
            app.PathSelectButton = uibutton(app.DataPathPanel, 'push');
            app.PathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @PathSelectButtonPushed, true);
            app.PathSelectButton.Tag = 'pathSelectButton';
            app.PathSelectButton.Text = '···';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end